classdef Transport_Simulation_GUI_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        GetInputFileButton              matlab.ui.control.Button
        TextTestEditFieldLabel          matlab.ui.control.Label
        TextTestEditField               matlab.ui.control.EditField
        NumericTestEditFieldLabel       matlab.ui.control.Label
        NumericTestEditField            matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        EnergyRangeMineVEditFieldLabel  matlab.ui.control.Label
        EnergyRangeMineVEditField       matlab.ui.control.NumericEditField
        EnergyRangeMaxeVEditFieldLabel  matlab.ui.control.Label
        EnergyRangeMaxeVEditField       matlab.ui.control.NumericEditField
        EnergySpacingeVEditFieldLabel   matlab.ui.control.Label
        EnergySpacingeVEditField        matlab.ui.control.NumericEditField
        GammaLeftContacteVEditFieldLabel  matlab.ui.control.Label
        GammaLeftContacteVEditField     matlab.ui.control.NumericEditField
        GammaRightContacteVEditFieldLabel  matlab.ui.control.Label
        GammaRightContacteVEditField    matlab.ui.control.NumericEditField
        InjectionSitesEditFieldLabel    matlab.ui.control.Label
        InjectionSitesEditField         matlab.ui.control.NumericEditField
        ExtractionSitesEditFieldLabel   matlab.ui.control.Label
        ExtractionSitesEditField        matlab.ui.control.NumericEditField
        BiaseVEditFieldLabel            matlab.ui.control.Label
        BiaseVEditField                 matlab.ui.control.NumericEditField
        SetFermiLevelEditFieldLabel     matlab.ui.control.Label
        SetFermiLevelEditField          matlab.ui.control.NumericEditField
        BroadeningEditFieldLabel        matlab.ui.control.Label
        BroadeningEditField             matlab.ui.control.NumericEditField
        ButtikerProbeSitesEditFieldLabel  matlab.ui.control.Label
        ButtikerProbeSitesEditField     matlab.ui.control.NumericEditField
        SimulatingLampLabel             matlab.ui.control.Label
        SimulatingLamp                  matlab.ui.control.Lamp
        DoneLampLabel                   matlab.ui.control.Label
        DoneLamp                        matlab.ui.control.Lamp
        SavetoEditFieldLabel            matlab.ui.control.Label
        SavetoEditField                 matlab.ui.control.EditField
        SaveTransmissionDataButton      matlab.ui.control.Button
        GZeroBiasEditFieldLabel         matlab.ui.control.Label
        GZeroBiasEditField              matlab.ui.control.NumericEditField
        TEfEditFieldLabel               matlab.ui.control.Label
        TEfEditField                    matlab.ui.control.NumericEditField
        dlnTEfdEEditFieldLabel          matlab.ui.control.Label
        dlnTEfdEEditField               matlab.ui.control.NumericEditField
        TempEditFieldLabel              matlab.ui.control.Label
        TempEditField                   matlab.ui.control.NumericEditField
        SeebeckCoefficientEditFieldLabel  matlab.ui.control.Label
        SeebeckCoefficientEditField     matlab.ui.control.NumericEditField
        HOMOeVEditFieldLabel            matlab.ui.control.Label
        HOMOeVEditField                 matlab.ui.control.NumericEditField
        LUMOeVEditFieldLabel            matlab.ui.control.Label
        LUMOeVEditField                 matlab.ui.control.NumericEditField
        MidGapeVEditFieldLabel          matlab.ui.control.Label
        MidGapeVEditField               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        H0 % Effective Hamiltonian from .LOG file
        Elements % Elements from Input file
        Orbitals % Orbitals associated with each atomic species
        Transmission % Calculated Transmission Data
        ERange % Energy Range For Calculated Transmission Data
    end

    methods (Access = public)
    
        function Ham = Get_Effective_Hamiltonian(app,filename)
            fileID = fopen(filename,'r');
            au_to_eV = 27.211396;
            iterations = 0;
            counter = 0;
            %Count Occurances of Fock Matrix
            str_match = ' Fock matrix (alpha):';
            while ~feof(fileID)
                t_line = fgetl(fileID);
                if strcmp(t_line,str_match) == 1
                    iterations = iterations + 1;
                else
                end
            end
            frewind(fileID)
            
            str_match = ' Calculate overlap and kinetic energy integrals';
            while(strcmp(t_line,str_match) ~= 1)
                t_line = fgetl(fileID);
            end
            
            formatSpec = '    NBasis =  %d';
            n = fscanf(fileID,formatSpec);
            t_line = fgetl(fileID);
            S_Mat = zeros(n,n);
            
            str_match = ' *** Overlap *** ';
            
            while(strcmp(t_line,str_match) ~= 1)
                t_line = fgetl(fileID);
            end
            
            t_line = fgetl(fileID);
            
            i = 1;
            tot_blocks = n/5;
            block = 0;
            j = 1;
            while(block < tot_blocks)
                while(i <= n)
                    t_line = fgetl(fileID);
                    line = str2num(t_line);
                    line = line(1,2:length(line));
                    S_Mat(i,(block*5+1):(length(line)+block*5)) = line;
                    i = i+1;
                end
                t_line = fgetl(fileID);
                block = block + 1;
                i = 1 + block*5;
            end
            S_Mat = (S_Mat + tril(S_Mat,-1)');
            
            
            str_match = ' Fock matrix (alpha):';
            while counter<iterations
                t_line = fgetl(fileID);
                if strcmp(t_line,str_match) == 1
                    counter = counter+1;
                else
                end
            end

%             while(strcmp(t_line,str_match) ~= 1)
%                 t_line = fgetl(fileID);
%             end
            
            t_line = fgetl(fileID);
            Fock_a = zeros(n,n);
            i = 1;
            tot_blocks = n/5;
            block = 0;
            j = 1;
            while(block < tot_blocks)
                while(i <= n)
                    t_line = fgetl(fileID);
                    line = str2num(t_line);
                    line = line(1,2:length(line));
                    Fock_a(i,(block*5+1):(length(line)+block*5)) = line;
                    i = i+1;
                end
                t_line = fgetl(fileID);
                block = block + 1;
                i = 1 + block*5;
            end
            
            Fock_a = (Fock_a + tril(Fock_a,-1)')*au_to_eV;
            
            Fock_b = zeros(n,n);
%             i = 1;
%             tot_blocks = n/5;
%             block = 0;
%             j = 1;
%             while(block < tot_blocks)
%                 while(i <= n)
%                     t_line = fgetl(fileID);
%                     line = str2num(t_line);
%                     line = line(1,2:length(line));
%                     Fock_b(i,(block*5+1):(length(line)+block*5)) = line;
%                     i = i+1;
%                 end
%                 t_line = fgetl(fileID);
%                 block = block + 1;
%                 i = i + block*5;
%             end
%             
%             Fock_b = (Fock_b + tril(Fock_b,-1)')*au_to_eV;
            
            Ham = (S_Mat^(-0.5))*Fock_a*(S_Mat^(-0.5));
            fclose('all');
        end
        
        function Atoms = Get_Atoms(app,filename)
            Atoms = "";
            fileID = fopen(filename,'r');
            temp_array = zeros(1,1000);
            str_match = ' Title Card Required';
            t_line = fgetl(fileID);
            while(strcmp(t_line,str_match) ~= 1)
                t_line = fgetl(fileID);
            end
            t_line = fgetl(fileID);
            t_line = fgetl(fileID);
            t_line = fgetl(fileID);
            t_line = fgetl(fileID);
            str_match = " ";
            atom_num = 0;
            while(strcmp(str_match,t_line) ~= 1)
                Atoms(atom_num+1) = convertCharsToStrings(strrep(t_line(1:4)," ",""))
                atom_num = atom_num + 1;
                t_line = fgetl(fileID);
            end
            fclose('all');
        end
        
        function Orbitals = Get_Orbitals(app,filename,Atoms)
            fileID = fopen(filename,'r');
            Natoms = length(Atoms);
            Orbitals = zeros(1,Natoms);
            str_match = " AO basis set (Overlap normalization):";
            t_line = fgetl(fileID);
            while(strcmp(str_match,t_line) ~= 1)
                t_line = fgetl(fileID);
            end
                t_line = fgetl(fileID);
                atom_num = str2num(erase(erase(t_line(1:11),lettersPattern)," "));
                shells = str2num(erase(t_line(30:35),lettersPattern));
                basis_functions = str2num(strrep(erase(t_line(40:60)," "),'-',' '));
                first_function = basis_functions(1);
                current_atom = 1;
                for i = 1:shells
                    t_line = fgetl(fileID);
                end
                t_line = fgetl(fileID);
            while(strcmp(t_line(1:8),' LdAtmC:') ~= 1)
                atom_num = str2num(erase(erase(t_line(1:11),lettersPattern)," "));
                shells = str2num(erase(t_line(30:35),lettersPattern));
                basis_functions = str2num(strrep(erase(t_line(40:60)," "),'-',' '));
                if atom_num == current_atom
                    Orbitals(atom_num) = basis_functions(2)-first_function+1;
                    for i = 1:shells
                        t_line = fgetl(fileID);
                    end
                else
                    current_atom = atom_num;
                    first_function  = basis_functions(1);
                    Orbitals(atom_num) = basis_functions(2);
                    for i = 1:shells
                        t_line = fgetl(fileID);
                    end
                end
                t_line = fgetl(fileID);
            end
        fclose('all');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetInputFileButton
        function GetInputFileButtonPushed(app, event)
           [filename path] = uigetfile('.LOG');
           Infile = strcat(path,filename);
           app.Elements = Get_Atoms(app,Infile);
           app.H0 = Get_Effective_Hamiltonian(app,Infile)
           app.FileNameEditField.Value = Infile;         
           app.Orbitals = Get_Orbitals(app,Infile,app.Elements);
           length(app.Orbitals)
           app.TextTestEditField.Value = char(app.Elements(1,end));
           app.NumericTestEditField.Value = app.Orbitals(1,end-1);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.SimulatingLamp.Color = [1 0 0];
            app.DoneLamp.Color = [0 0 0];
            insite = app.InjectionSitesEditField.Value;
            exsite = app.ExtractionSitesEditField.Value;
            gammaL = app.GammaLeftContacteVEditField.Value;
            gammaR = app.GammaRightContacteVEditField.Value;
            Emin = app.EnergyRangeMineVEditField.Value;
            Emax = app.EnergyRangeMaxeVEditField.Value;
            dE = app.EnergySpacingeVEditField.Value;
            Ef = app.SetFermiLevelEditField.Value;
            Orbs = app.Orbitals;
            Vb = app.BiaseVEditField.Value;
            H = app.H0;
            sizeH = size(H);
            Sigma = zeros(sizeH);
            eta = 1E-10;
            
            E = Emin:dE:Emax;
            NE = length(E);
            %Build Self Energy Matrices
            TempLenL1 = sum(Orbs(1:insite(end)))-sum(Orbs(insite));
            TempLenL2 = sum(Orbs(1:insite(end)));
            GammaL = gammaL*eye(sum(Orbs(insite)));
            Sigma(TempLenL1 + 1 : TempLenL2, TempLenL1 + 1 : TempLenL2) = GammaL;
            
            TempLenR1 = sum(Orbs(1 : exsite(end))) - sum(Orbs(exsite));
            TempLenR2 = sum(Orbs(1 : exsite(end)));
            GammaR = gammaR*eye(sum(Orbs(exsite)));
            Sigma(TempLenR1 + 1 : TempLenR2, TempLenR1 + 1 : TempLenR2) = GammaR;
            
            Sigma = -1i*Sigma/2;
            %Build Retarded and Advanced Green's Function Matrices
            T = ones(1,NE);
            for nE = 1:NE
                Gr = eye(sizeH)/((E(nE) + 1i*eta)*eye(sizeH) - H - Sigma);
                Ga = Gr';
                T(nE) = real(trace(GammaL*Gr(TempLenL1 + 1 : TempLenL2, TempLenR1 + 1 : TempLenR2) * GammaR * Ga(TempLenR1 + 1 : TempLenR2, TempLenL1 + 1 : TempLenL2)));
            end
            
            app.ERange = E;
            app.Transmission = T;
            %Plot T(E)
            semilogy(app.UIAxes,E,T)
            hold(app.UIAxes,'on')
            y1 = [1E-15 10];
            semilogy(app.UIAxes,[Ef Ef],y1,'r');
            %plot(app.UIAxes,[Ef Ef],y1,'r');
            ylim(app.UIAxes,[1E-9 1.6])
            hold(app.UIAxes,'off')
            
            %Calculate G(E)
            %Int(T(E) dE,Ef,Ef+Vbias)
%             if (Vb ~= 0)
%                 Eg = ((Ef-Vb/2)):dE:((Ef+Vb/2))
%                 NEg = length(Eg);
%                 Tg = -1*ones(1,NEg);
%                 for i = 1:NEg
%                     Gr = eye(sizeH)/((Eg(i) + 1i*eta)*eye(sizeH) - H - Sigma)
%                     Ga = Gr';
%                     Tg(i) = real(trace(gammaL*Gr(TempLenL1 + 1 : TempLenL2, TempLenR1 + 1 : TempLenR2) * GammaR * Ga(TempLenR1 + 1 : TempLenR2, TempLenL1 + 1 : TempLenL2)));
%                 end
%                 G = trapz(Tg);
%             else
%                 G = 0;
%             end
            
            %Calculate T(Ef)
            Gr = eye(sizeH)/((Ef + 1i*eta)*eye(sizeH) - H - Sigma);
            Ga = Gr';
            TEf = real(trace(gammaL*Gr(TempLenL1 + 1 : TempLenL2, TempLenR1 + 1 : TempLenR2) * GammaR * Ga(TempLenR1 + 1 : TempLenR2, TempLenL1 + 1 : TempLenL2)));
            app.TEfEditField.Value = TEf;
            app.GZeroBiasEditField.Value = TEf/77.48E-6;
            
            %Calculate derivative of natural log of the Transmission with respect to E and evaluate at Ef
            lnT = log(T);
            dTdE = diff(lnT)./dE;
            [M I] = min(abs(E-Ef));
            app.dlnTEfdEEditField.Value = dTdE(I);
            
            %Calculate Seebeck Coefficient from previous step
            charge = 1
            kb = 8.617E-5;
            TempK = app.TempEditField.Value;
            Sc = (-(pi^2*kb^2*TempK)/(3*charge))*dTdE(I);
            app.SeebeckCoefficientEditField.Value = Sc;
            
            %Find HOMO, LUMO, and Mid-Gap
            [pks locs] = findpeaks(T);
            Elvls = E(locs);
            Hind = Elvls < Ef;
            HOMO = Elvls(Hind);
            HOMO = HOMO(end);
            Lind = Elvls > Ef;
            LUMO = Elvls(Lind);
            LUMO = LUMO(1);
            Midgap = mean([HOMO LUMO]);
            
            app.HOMOeVEditField.Value = HOMO;
            app.LUMOeVEditField.Value = LUMO;
            app.MidGapeVEditField.Value = Midgap;
            
            app.SimulatingLamp.Color = [0 0 0];
            app.DoneLamp.Color = [0 1 0];
            pause(3);
            app.DoneLamp.Color = [0 0 0];
        end

        % Button pushed function: SaveTransmissionDataButton
        function SaveTransmissionDataButtonPushed(app, event)
            filename = app.SavetoEditField.Value;
            fid = fopen(filename,'a');
            dat_len = length(app.Transmission);
            for i = 1:1:dat_len
                fprintf(fid,'%.6f %.9f\n',app.ERange(i),app.Transmission(i));
            end
            fclose('all');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.GetInputFileButton = uibutton(app.UIFigure, 'push');
            app.GetInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetInputFileButtonPushed, true);
            app.GetInputFileButton.Text = 'Get Input File';
            app.TextTestEditFieldLabel = uilabel(app.UIFigure);
            app.TextTestEditFieldLabel.Text = 'Text Test';
            app.TextTestEditField = uieditfield(app.UIFigure, 'text');
            app.NumericTestEditFieldLabel = uilabel(app.UIFigure);
            app.NumericTestEditFieldLabel.Text = 'Numeric Test';
            app.NumericTestEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.EnergyRangeMineVEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyRangeMineVEditFieldLabel.Text = 'Energy Range Min. (eV)';
            app.EnergyRangeMineVEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyRangeMineVEditField.Value = -10;
            app.EnergyRangeMaxeVEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyRangeMaxeVEditFieldLabel.Text = 'Energy Range Max (eV)';
            app.EnergyRangeMaxeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyRangeMaxeVEditField.Value = 5;
            app.EnergySpacingeVEditFieldLabel = uilabel(app.UIFigure);
            app.EnergySpacingeVEditFieldLabel.Text = 'Energy Spacing (eV)';
            app.EnergySpacingeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergySpacingeVEditField.Value = 0.01;
            app.GammaLeftContacteVEditFieldLabel = uilabel(app.UIFigure);
            app.GammaLeftContacteVEditFieldLabel.Text = 'Gamma Left Contact (eV)';
            app.GammaLeftContacteVEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaLeftContacteVEditField.Value = 0.001;
            app.GammaRightContacteVEditFieldLabel = uilabel(app.UIFigure);
            app.GammaRightContacteVEditFieldLabel.Text = 'Gamma Right Contact (eV)';
            app.GammaRightContacteVEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaRightContacteVEditField.Value = 0.001;
            app.InjectionSitesEditFieldLabel = uilabel(app.UIFigure);
            app.InjectionSitesEditFieldLabel.Text = 'Injection Sites';
            app.InjectionSitesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InjectionSitesEditField.Value = 1;
            app.ExtractionSitesEditFieldLabel = uilabel(app.UIFigure);
            app.ExtractionSitesEditFieldLabel.Text = 'Extraction Sites';
            app.ExtractionSitesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExtractionSitesEditField.Value = 10;
            app.BiaseVEditFieldLabel = uilabel(app.UIFigure);
            app.BiaseVEditFieldLabel.Text = 'Bias (eV)';
            app.BiaseVEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetFermiLevelEditFieldLabel = uilabel(app.UIFigure);
            app.SetFermiLevelEditFieldLabel.Text = 'Set Fermi Level';
            app.SetFermiLevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.BroadeningEditFieldLabel = uilabel(app.UIFigure);
            app.BroadeningEditFieldLabel.Text = 'Broadening';
            app.BroadeningEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtikerProbeSitesEditFieldLabel = uilabel(app.UIFigure);
            app.ButtikerProbeSitesEditFieldLabel.Text = 'Buttiker Probe Sites';
            app.ButtikerProbeSitesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulatingLampLabel = uilabel(app.UIFigure);
            app.SimulatingLampLabel.Text = 'Simulating';
            app.SimulatingLamp = uilamp(app.UIFigure);
            app.DoneLampLabel = uilabel(app.UIFigure);
            app.DoneLampLabel.Text = 'Done!';
            app.DoneLamp = uilamp(app.UIFigure);
            app.SavetoEditFieldLabel = uilabel(app.UIFigure);
            app.SavetoEditFieldLabel.Text = 'Save to:';
            app.SavetoEditField = uieditfield(app.UIFigure, 'text');
            app.SavetoEditField.Value = '.txt';
            app.SaveTransmissionDataButton = uibutton(app.UIFigure, 'push');
            app.SaveTransmissionDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTransmissionDataButtonPushed, true);
            app.SaveTransmissionDataButton.Text = 'Save Transmission Data';
            app.GZeroBiasEditFieldLabel = uilabel(app.UIFigure);
            app.GZeroBiasEditFieldLabel.Text = 'G Zero-Bias';
            app.GZeroBiasEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEfEditFieldLabel = uilabel(app.UIFigure);
            app.TEfEditFieldLabel.Text = 'T(Ef)';
            app.TEfEditField = uieditfield(app.UIFigure, 'numeric');
            app.dlnTEfdEEditFieldLabel = uilabel(app.UIFigure);
            app.dlnTEfdEEditFieldLabel.Text = 'd(lnT(Ef))/dE';
            app.dlnTEfdEEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempEditFieldLabel = uilabel(app.UIFigure);
            app.TempEditFieldLabel.Text = 'Temp';
            app.TempEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempEditField.Value = 298;
            app.SeebeckCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.SeebeckCoefficientEditFieldLabel.Text = 'Seebeck Coefficient';
            app.SeebeckCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.HOMOeVEditFieldLabel = uilabel(app.UIFigure);
            app.HOMOeVEditFieldLabel.Text = 'HOMO (eV):';
            app.HOMOeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.LUMOeVEditFieldLabel = uilabel(app.UIFigure);
            app.LUMOeVEditFieldLabel.Text = 'LUMO (eV):';
            app.LUMOeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MidGapeVEditFieldLabel = uilabel(app.UIFigure);
            app.MidGapeVEditFieldLabel.Text = 'Mid-Gap (eV)';
            app.MidGapeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ballistic Transmission Plot')
            xlabel(app.UIAxes, 'Energy (eV)')
            ylabel(app.UIAxes, 'Log(T(E))')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transport_Simulation_GUI_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end